classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        plotButton  matlab.ui.control.Button
        Label       matlab.ui.control.Label
        Time        matlab.ui.control.NumericEditField
        loadBurton  matlab.ui.control.Button
        Label_2     matlab.ui.control.Label
        step        matlab.ui.control.NumericEditField
        Label_3     matlab.ui.control.Label
        Target      matlab.ui.control.NumericEditField
        Label_4     matlab.ui.control.Label
        Stations    matlab.ui.control.DropDown
        Format      matlab.ui.container.ButtonGroup
        Biomassa    matlab.ui.control.RadioButton
        Population  matlab.ui.control.RadioButton
        Label_5     matlab.ui.control.Label
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        CheckBox_3  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Data
        time_stations
        col
        range
        labely
        graph
    end
    

    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.Label_5.Text = 'Программа ищет решения';
            selectedButton = app.Format.SelectedObject;
            if selectedButton == app.Biomassa
                app.col = 3;
                app.labely = app.Biomassa.Text;
                type = 1;
            else
                app.col = 2;
                type = 0;
                app.labely = app.Population.Text;
            end
            value = app.Stations.Value;
            for i=1:3:size(app.time_stations, 1)
                if strcmp(value, app.time_stations{i,2})
                    app.range = i;
                end
            end
            h = get(app.step, 'Value');
            k = get(app.Target, 'Value');
            app.UIAxes.YLabel.String = app.labely;
            x1 = app.Data(app.range:app.range+2, app.col);
            x2 = app.Data(app.range:app.range+2, app.col + 2);
            N = app.time_stations{app.range+2,1};
            data = plot_system_full(h, x1, x2, k, app.time_stations{app.range:app.range+2,1}, type);
            if ischar(data)
                app.Label_5.Text = data;
            else
                app.graph = plot(app.UIAxes, 0:h:N, data);
                app.Label_5.Text = 'Решение системы на графике';
                app.UIAxes.XLim = [0 N];
            end
        end

        % Button pushed function: loadBurton
        function loadBurtonPushed(app, event)
            FILE = uigetfile("*.xlsx");
            app.Data = parse_excel(FILE);
            app.time_stations = parse_date(FILE);
            app.Time.Value = app.time_stations{3,1};
            rows = size(app.time_stations, 1);
            app.Stations.Items = app.time_stations{1:3:rows,2};
        end

        % Value changed function: Stations
        function StationsValueChanged(app, event)
            value = app.Stations.Value;
            for i=1:3:size(app.time_stations, 1)
                if strcmp(value, app.time_stations{i,2})
                    app.Time.Value = app.time_stations{i + 2, 1};
                end
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.graph(1,:).Visible = 'on';
            else
                app.graph(1,:).Visible = 'off';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
             value = app.CheckBox_2.Value;
            if value == 1
                app.graph(2,:).Visible = 'on';
            else
                app.graph(2,:).Visible = 'off';
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
             value = app.CheckBox_3.Value;
            if value == 1
                app.graph(3,:).Visible = 'on';
            else
                app.graph(3,:).Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Модель "хищник-жертва с питанием"')
            xlabel(app.UIAxes, 'Время, дни')
            ylabel(app.UIAxes, 'Y')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'Построить график';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Время';
            app.Time = uieditfield(app.UIFigure, 'numeric');
            app.loadBurton = uibutton(app.UIFigure, 'push');
            app.loadBurton.ButtonPushedFcn = createCallbackFcn(app, @loadBurtonPushed, true);
            app.loadBurton.Text = 'Загрузить данные';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Шаг';
            app.step = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Увеличение';
            app.Target = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Станция';
            app.Stations = uidropdown(app.UIFigure);
            app.Stations.ValueChangedFcn = createCallbackFcn(app, @StationsValueChanged, true);
            app.Format = uibuttongroup(app.UIFigure);
            app.Format.Title = 'Формат исходных данных';
            app.Biomassa = uiradiobutton(app.Format);
            app.Biomassa.Text = 'Биомасса (мг/м3)';
            app.Biomassa.Value = true;
            app.Population = uiradiobutton(app.Format);
            app.Population.Text = 'Численность (экз/л)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Фитопланктон';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'Зоопланктон';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = 'Питание';
            app.CheckBox_3.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end